classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TestButton                 matlab.ui.control.Button
        RESULTSPanel               matlab.ui.container.Panel
        EditField_3Label_4         matlab.ui.control.Label
        FScoreEditField_STD        matlab.ui.control.NumericEditField
        FScoreEditField            matlab.ui.control.NumericEditField
        FScoreEditFieldLabel       matlab.ui.control.Label
        EditField_3Label_2         matlab.ui.control.Label
        SpecificityEditField_STD   matlab.ui.control.NumericEditField
        SpecificityEditField       matlab.ui.control.NumericEditField
        SpecificityEditFieldLabel  matlab.ui.control.Label
        EditField_3Label           matlab.ui.control.Label
        SensitivityEditField_STD   matlab.ui.control.NumericEditField
        SensitivityEditField       matlab.ui.control.NumericEditField
        SensitivityLabel           matlab.ui.control.Label
        AccuracyEditField_STD      matlab.ui.control.NumericEditField
        EditField_2Label           matlab.ui.control.Label
        AccuracyEditField          matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel     matlab.ui.control.Label
        ModelSelectionPanel        matlab.ui.container.Panel
        NIterationsDropDown        matlab.ui.control.DropDown
        NIterationsLabel           matlab.ui.control.Label
        ModelLabel                 matlab.ui.control.Label
        ModelDropDown              matlab.ui.control.DropDown
        FeatureSelectionPanel      matlab.ui.container.Panel
        NumberFeaturesDropDown     matlab.ui.control.DropDown
        NumberLabel                matlab.ui.control.Label
        FeaturesLabel              matlab.ui.control.Label
        FeaturesDropDown           matlab.ui.control.DropDown
        HeartDiseasesLabel         matlab.ui.control.Label
        ScenarioDropDown           matlab.ui.control.DropDown
        ScenarioDropDownLabel      matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            scenario = app.ScenarioDropDown.Value;
            features = app.FeaturesDropDown.Value;
            n_features = app.NumberFeaturesDropDown.Value;
            model = app.ModelDropDown.Value;
            n_iterations = app.NIterationsDropDown.Value;

            [Accuracy, Accuracy_std , Sensivity, Sensivity_std, Specificity, Specificity_std, FScore, FScore_std] = guiTest(scenario, features, n_features, model, n_iterations);

            app.AccuracyEditField.Value = Accuracy;
            app.AccuracyEditField_STD.Value = Accuracy_std;

            app.SensitivityEditField.Value = Sensivity;
            app.SensitivityEditField_STD.Value = Sensivity_std;

            app.SpecificityEditField.Value = Specificity;
            app.SpecificityEditField_STD.Value = Specificity_std;

            app.FScoreEditField.Value = FScore;
            app.FScoreEditField_STD.Value = FScore_std;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ScenarioDropDownLabel = uilabel(app.UIFigure);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.UIFigure);
            app.ScenarioDropDown.Items = {'1', '2', '3'};
            app.ScenarioDropDown.Value = '1';
            app.HeartDiseasesLabel = uilabel(app.UIFigure);
            app.HeartDiseasesLabel.Text = 'Heart Diseases';
            app.FeatureSelectionPanel = uipanel(app.UIFigure);
            app.FeatureSelectionPanel.Title = '1. Feature Selection';
            app.FeaturesDropDown = uidropdown(app.FeatureSelectionPanel);
            app.FeaturesDropDown.Items = {'PCA', 'Kruskal-Wallis', 'Fishers LD', 'ROC Curve'};
            app.FeaturesDropDown.Value = 'PCA';
            app.FeaturesLabel = uilabel(app.FeatureSelectionPanel);
            app.FeaturesLabel.Text = 'Features';
            app.NumberLabel = uilabel(app.FeatureSelectionPanel);
            app.NumberLabel.Text = 'Number';
            app.NumberFeaturesDropDown = uidropdown(app.FeatureSelectionPanel);
            app.NumberFeaturesDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.NumberFeaturesDropDown.Value = '1';
            app.ModelSelectionPanel = uipanel(app.UIFigure);
            app.ModelSelectionPanel.Title = '2. Model Selection';
            app.ModelDropDown = uidropdown(app.ModelSelectionPanel);
            app.ModelDropDown.Items = {'Linear MDC', 'Mahal MDC', 'Fishers LD', 'Bayes Classifier', 'KNN Classifier', 'SVMs'};
            app.ModelDropDown.Value = 'Linear MDC';
            app.ModelLabel = uilabel(app.ModelSelectionPanel);
            app.ModelLabel.Text = 'Model';
            app.NIterationsLabel = uilabel(app.ModelSelectionPanel);
            app.NIterationsLabel.Text = 'N Iterations';
            app.NIterationsDropDown = uidropdown(app.ModelSelectionPanel);
            app.NIterationsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.NIterationsDropDown.Value = '1';
            app.RESULTSPanel = uipanel(app.UIFigure);
            app.RESULTSPanel.Title = 'RESULTS';
            app.AccuracyEditFieldLabel = uilabel(app.RESULTSPanel);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_2Label = uilabel(app.RESULTSPanel);
            app.EditField_2Label.Text = '±';
            app.AccuracyEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.SensitivityLabel = uilabel(app.RESULTSPanel);
            app.SensitivityLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.SensitivityEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label = uilabel(app.RESULTSPanel);
            app.EditField_3Label.Text = '±';
            app.SpecificityEditFieldLabel = uilabel(app.RESULTSPanel);
            app.SpecificityEditFieldLabel.Text = 'Specificity';
            app.SpecificityEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.SpecificityEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label_2 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_2.Text = '±';
            app.FScoreEditFieldLabel = uilabel(app.RESULTSPanel);
            app.FScoreEditFieldLabel.Text = 'F-Score';
            app.FScoreEditField = uieditfield(app.RESULTSPanel, 'numeric');
            app.FScoreEditField_STD = uieditfield(app.RESULTSPanel, 'numeric');
            app.EditField_3Label_4 = uilabel(app.RESULTSPanel);
            app.EditField_3Label_4.Text = '±';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end